classdef app1_autoreflow2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        CalculateButton            matlab.ui.control.Button
        LineSpacingPanel           matlab.ui.container.Panel
        d3EditField                matlab.ui.control.EditField
        d3EditFieldLabel           matlab.ui.control.Label
        d2EditField                matlab.ui.control.EditField
        d2EditFieldLabel           matlab.ui.control.Label
        d1EditField                matlab.ui.control.EditField
        d1EditFieldLabel           matlab.ui.control.Label
        LinespacingDropDown        matlab.ui.control.DropDown
        LinespacingDropDownLabel   matlab.ui.control.Label
        LinespecificationsPanel    matlab.ui.container.Panel
        ResistivityEditField       matlab.ui.control.EditField
        ResistivityEditFieldLabel  matlab.ui.control.Label
        DiameterEditField          matlab.ui.control.EditField
        DiameterEditFieldLabel     matlab.ui.control.Label
        LengthEditField            matlab.ui.control.EditField
        LengthEditFieldLabel       matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        ABCDparametersPanel        matlab.ui.container.Panel
        AEditField                 matlab.ui.control.EditField
        AEditField_5Label          matlab.ui.control.Label
        RCLvaluesPanel             matlab.ui.container.Panel
        CEditField                 matlab.ui.control.EditField
        CEditFieldLabel            matlab.ui.control.Label
        DEditField                 matlab.ui.control.EditField
        DEditFieldLabel            matlab.ui.control.Label
        BEditField                 matlab.ui.control.EditField
        BEditFieldLabel            matlab.ui.control.Label
        InductanceEditField        matlab.ui.control.EditField
        InductanceEditFieldLabel   matlab.ui.control.Label
        CapacitanceEditField       matlab.ui.control.EditField
        CapacitanceEditFieldLabel  matlab.ui.control.Label
        ResistanceEditField        matlab.ui.control.EditField
        ResistanceEditFieldLabel   matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        isSymmetrical % Identifies whether if the system is symmetrical or not
        defaultColor = [0.50,0.50,0.50] % default color
        length % Description
        diameter % Description
        resistivity % Description
        res % resistance
        cap % capacitance
        ind % inductance
        A % Description
        B % Description
        C % Description
        D % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LinespacingDropDown.Items = ["Symmetrical" "Unsymmetrical"];
            app.isSymmetrical = true;
            app.d2EditField.Editable = false;
            app.d3EditField.Editable = false;
            app.ResistanceEditField.Editable = false;
            app.CapacitanceEditField.Editable = false;
            app.InductanceEditField.Editable = false;
            app.AEditField.Editable = false;
            app.BEditField.Editable = false;
            app.CEditField.Editable = false;
            app.DEditField.Editable = false;
        end

        % Value changing function: LengthEditField
        function LengthEditFieldValueChanging(app, event)
            app.LengthEditField.FontColor = [0.0, 0.0, 0.0];
            changingValue = event.Value;
            if strcmpi(changingValue, '')
                app.LengthEditField.FontColor = app.defaultColor;
            end
        end

        % Value changing function: DiameterEditField
        function DiameterEditFieldValueChanging(app, event)
            app.DiameterEditField.FontColor = [0.0, 0.0, 0.0];
            changingValue = event.Value;
            if strcmpi(changingValue, '')
                app.DiameterEditField.FontColor = app.defaultColor;
            end
        end

        % Value changing function: ResistivityEditField
        function ResistivityEditFieldValueChanging(app, event)
            app.ResistivityEditField.FontColor = [0.0, 0.0, 0.0];
            changingValue = event.Value;
            if strcmpi(changingValue, '')
                app.ResistivityEditField.FontColor = app.defaultColor;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {476, 476};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {335, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: LinespacingDropDown
        function LinespacingDropDownValueChanged(app, event)
            value = app.LinespacingDropDown.Value;
            app.d1EditField.Value = '';
            app.d2EditField.Value = '';
            app.d3EditField.Value = '';
            app.d1EditField.FontColor = app.defaultColor;
            app.d2EditField.FontColor = app.defaultColor;
            app.d3EditField.FontColor = app.defaultColor;
            if strcmpi(value, 'Unsymmetrical')
                app.isSymmetrical = false;
                app.d2EditField.Editable = true;
                app.d3EditField.Editable = true;
            else
                app.isSymmetrical = true;
                app.d2EditField.Editable = false;
                app.d3EditField.Editable = false;
            end
        end

        % Value changing function: d1EditField
        function d1EditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.d1EditField.FontColor = [0.0, 0.0, 0.0];
            if strcmpi(changingValue, '')
                app.d1EditField.FontColor = app.defaultColor;
            end
            if app.isSymmetrical
                app.d2EditField.Value = changingValue;
                app.d3EditField.Value = changingValue;
            end
        end

        % Value changing function: d2EditField
        function d2EditFieldValueChanging(app, event)
            app.d2EditField.FontColor = [0.0, 0.0, 0.0];
            changingValue = event.Value;
            if strcmpi(changingValue, '')
                app.d2EditField.FontColor = app.defaultColor;
            end
        end

        % Value changing function: d3EditField
        function d3EditFieldValueChanging(app, event)
            app.d3EditField.FontColor = [0.0, 0.0, 0.0];
            changingValue = event.Value;
            if strcmpi(changingValue, '')
                app.d3EditField.FontColor = app.defaultColor;
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.length = str2num(app.LengthEditField.Value);
            app.diameter = str2num(app.DiameterEditField.Value);
            app.resistivity = str2num(app.ResistivityEditField.Value);
            d1 = str2num(app.d1EditField.Value);
            d2 = str2num(app.d2EditField.Value);
            d3 = str2num(app.d3EditField.Value);
            
            
            if isnan(app.length) || isnan(app.diameter) || isnan(app.resistivity) || isnan(d1) || isnan(d2) || isnan(d3)
                warndlg('Input must be numerical');
            
            else
                dis = d1 * d2 * d3;
                GMD = nthroot(dis, 3);
                [app.res, app.cap, app.ind] = rcl(app.length, app.diameter, app.resistivity, GMD);
                
                app.ResistanceEditField.Value = num2str(app.res) + " Ω";
                app.CapacitanceEditField.Value = num2str(app.cap) + " F";
                app.InductanceEditField.Value = num2str(app.ind) + " H";
                
                [app.A, app.B, app.C, app.D] = lineParameters(app.length, app.res, app.cap, app.ind);
                
                app.AEditField.Value = num2str(app.A);
                app.BEditField.Value = num2str(app.B);
                app.CEditField.Value = num2str(app.C);
                app.DEditField.Value = num2str(app.D);
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LinespecificationsPanel = uipanel(app.LeftPanel);
            app.LinespecificationsPanel.Title = 'Line specifications';
            app.LengthEditFieldLabel = uilabel(app.LinespecificationsPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.LinespecificationsPanel, 'text');
            app.LengthEditField.ValueChangingFcn = createCallbackFcn(app, @LengthEditFieldValueChanging, true);
            app.LengthEditField.Placeholder = 'length in km';
            app.DiameterEditFieldLabel = uilabel(app.LinespecificationsPanel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.LinespecificationsPanel, 'text');
            app.DiameterEditField.ValueChangingFcn = createCallbackFcn(app, @DiameterEditFieldValueChanging, true);
            app.DiameterEditField.Placeholder = 'diameter in cm';
            app.ResistivityEditFieldLabel = uilabel(app.LinespecificationsPanel);
            app.ResistivityEditFieldLabel.Text = 'Resistivity';
            app.ResistivityEditField = uieditfield(app.LinespecificationsPanel, 'text');
            app.ResistivityEditField.ValueChangingFcn = createCallbackFcn(app, @ResistivityEditFieldValueChanging, true);
            app.ResistivityEditField.Placeholder = 'resistivity in Ω⋅m';
            app.LineSpacingPanel = uipanel(app.LeftPanel);
            app.LineSpacingPanel.Title = 'Line Spacing';
            app.LinespacingDropDownLabel = uilabel(app.LineSpacingPanel);
            app.LinespacingDropDownLabel.Text = 'Line spacing';
            app.LinespacingDropDown = uidropdown(app.LineSpacingPanel);
            app.LinespacingDropDown.Items = {};
            app.LinespacingDropDown.ValueChangedFcn = createCallbackFcn(app, @LinespacingDropDownValueChanged, true);
            app.LinespacingDropDown.Placeholder = 'Line spacing';
            app.LinespacingDropDown.Value = {};
            app.d1EditFieldLabel = uilabel(app.LineSpacingPanel);
            app.d1EditFieldLabel.Text = 'd1';
            app.d1EditField = uieditfield(app.LineSpacingPanel, 'text');
            app.d1EditField.ValueChangingFcn = createCallbackFcn(app, @d1EditFieldValueChanging, true);
            app.d1EditField.Placeholder = 'Distance between a and b (in m)';
            app.d2EditFieldLabel = uilabel(app.LineSpacingPanel);
            app.d2EditFieldLabel.Text = 'd2';
            app.d2EditField = uieditfield(app.LineSpacingPanel, 'text');
            app.d2EditField.ValueChangingFcn = createCallbackFcn(app, @d2EditFieldValueChanging, true);
            app.d2EditField.Placeholder = 'Distance between b and c (in m)';
            app.d3EditFieldLabel = uilabel(app.LineSpacingPanel);
            app.d3EditFieldLabel.Text = 'd3';
            app.d3EditField = uieditfield(app.LineSpacingPanel, 'text');
            app.d3EditField.ValueChangingFcn = createCallbackFcn(app, @d3EditFieldValueChanging, true);
            app.d3EditField.Placeholder = 'Distance between c and a (in m)';
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.RightPanel = uipanel(app.GridLayout);
            app.RCLvaluesPanel = uipanel(app.RightPanel);
            app.RCLvaluesPanel.Title = 'RCL values';
            app.ResistanceEditFieldLabel = uilabel(app.RCLvaluesPanel);
            app.ResistanceEditFieldLabel.Text = 'Resistance';
            app.ResistanceEditField = uieditfield(app.RCLvaluesPanel, 'text');
            app.CapacitanceEditFieldLabel = uilabel(app.RCLvaluesPanel);
            app.CapacitanceEditFieldLabel.Text = 'Capacitance';
            app.CapacitanceEditField = uieditfield(app.RCLvaluesPanel, 'text');
            app.InductanceEditFieldLabel = uilabel(app.RCLvaluesPanel);
            app.InductanceEditFieldLabel.Text = 'Inductance';
            app.InductanceEditField = uieditfield(app.RCLvaluesPanel, 'text');
            app.ABCDparametersPanel = uipanel(app.RightPanel);
            app.ABCDparametersPanel.Title = 'ABCD parameters';
            app.BEditFieldLabel = uilabel(app.ABCDparametersPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.ABCDparametersPanel, 'text');
            app.DEditFieldLabel = uilabel(app.ABCDparametersPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.ABCDparametersPanel, 'text');
            app.CEditFieldLabel = uilabel(app.ABCDparametersPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.ABCDparametersPanel, 'text');
            app.AEditField_5Label = uilabel(app.ABCDparametersPanel);
            app.AEditField_5Label.Text = 'A';
            app.AEditField = uieditfield(app.ABCDparametersPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_autoreflow2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end